classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        generalTab                   matlab.ui.container.Tab
        DropDown_7                   matlab.ui.control.DropDown
        DropDown_6                   matlab.ui.control.DropDown
        DropDown_5                   matlab.ui.control.DropDown
        DropDown_4                   matlab.ui.control.DropDown
        DropDown_3                   matlab.ui.control.DropDown
        DropDown                     matlab.ui.control.DropDown
        ButtonGroup_6                matlab.ui.container.ButtonGroup
        NnoneButton_6                matlab.ui.control.RadioButton
        PprizmaticButton_6           matlab.ui.control.RadioButton
        RrevoluteButton_6            matlab.ui.control.RadioButton
        ButtonGroup_5                matlab.ui.container.ButtonGroup
        NnoneButton_5                matlab.ui.control.RadioButton
        PprizmaticButton_5           matlab.ui.control.RadioButton
        RrevoluteButton_5            matlab.ui.control.RadioButton
        ButtonGroup_4                matlab.ui.container.ButtonGroup
        NnoneButton_4                matlab.ui.control.RadioButton
        PprizmaticButton_4           matlab.ui.control.RadioButton
        RrevoluteButton_4            matlab.ui.control.RadioButton
        ButtonGroup_3                matlab.ui.container.ButtonGroup
        NnoneButton_3                matlab.ui.control.RadioButton
        PprizmaticButton_3           matlab.ui.control.RadioButton
        RrevoluteButton_3            matlab.ui.control.RadioButton
        ButtonGroup_2                matlab.ui.container.ButtonGroup
        NnoneButton_2                matlab.ui.control.RadioButton
        PprizmaticButton_2           matlab.ui.control.RadioButton
        RrevoluteButton_2            matlab.ui.control.RadioButton
        ButtonGroup                  matlab.ui.container.ButtonGroup
        NnoneButton                  matlab.ui.control.RadioButton
        PprizmaticButton             matlab.ui.control.RadioButton
        RrevoluteButton              matlab.ui.control.RadioButton
        LinkPositionTextArea         matlab.ui.control.TextArea
        LinkPositionTextAreaLabel    matlab.ui.control.Label
        JacobianmatrixTextArea       matlab.ui.control.TextArea
        JacobianmatrixTextAreaLabel  matlab.ui.control.Label
        calculateJacobianButton      matlab.ui.control.Button
        plotButton                   matlab.ui.control.Button
        theta6Slider                 matlab.ui.control.Slider
        theta6SliderLabel            matlab.ui.control.Label
        theta5Slider                 matlab.ui.control.Slider
        theta5SliderLabel            matlab.ui.control.Label
        theta4Slider                 matlab.ui.control.Slider
        theta4SliderLabel            matlab.ui.control.Label
        theta3Slider                 matlab.ui.control.Slider
        theta3SliderLabel            matlab.ui.control.Label
        theta2Slider                 matlab.ui.control.Slider
        theta2SliderLabel            matlab.ui.control.Label
        theta1Slider                 matlab.ui.control.Slider
        theta1SliderLabel            matlab.ui.control.Label
        TmatrixTextArea              matlab.ui.control.TextArea
        TmatrixTextAreaLabel         matlab.ui.control.Label
        edit_alpha_1                 matlab.ui.control.NumericEditField
        calculateTmatrixButton       matlab.ui.control.Button
        TextArea_2                   matlab.ui.control.TextArea
        Label_7                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        edit_a_1                     matlab.ui.control.NumericEditField
        edit_d_1                     matlab.ui.control.NumericEditField
        TextArea                     matlab.ui.control.TextArea
        edit_theta_6                 matlab.ui.control.NumericEditField
        edit_theta_5                 matlab.ui.control.NumericEditField
        edit_theta_4                 matlab.ui.control.NumericEditField
        edit_theta_3                 matlab.ui.control.NumericEditField
        edit_theta_2                 matlab.ui.control.NumericEditField
        edit_d_6                     matlab.ui.control.NumericEditField
        edit_d_5                     matlab.ui.control.NumericEditField
        edit_d_4                     matlab.ui.control.NumericEditField
        edit_d_3                     matlab.ui.control.NumericEditField
        edit_d_2                     matlab.ui.control.NumericEditField
        edit_a_6                     matlab.ui.control.NumericEditField
        edit_a_5                     matlab.ui.control.NumericEditField
        edit_a_4                     matlab.ui.control.NumericEditField
        edit_a_3                     matlab.ui.control.NumericEditField
        edit_a_2                     matlab.ui.control.NumericEditField
        edit_alpha_6                 matlab.ui.control.NumericEditField
        edit_alpha_5                 matlab.ui.control.NumericEditField
        edit_alpha_4                 matlab.ui.control.NumericEditField
        edit_alpha_3                 matlab.ui.control.NumericEditField
        edit_alpha_2                 matlab.ui.control.NumericEditField
        edit_theta_1                 matlab.ui.control.NumericEditField
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
        KUKAKR8R1420HWTab            matlab.ui.container.Tab
        TextArea_4                   matlab.ui.control.TextArea
        InverseKinematicsCCDmethodButton  matlab.ui.control.Button
        InverseKinematicsFABRIKmethodButton  matlab.ui.control.Button
        LinkPositionTextArea_2       matlab.ui.control.TextArea
        LinkPositionTextArea_2Label  matlab.ui.control.Label
        TextArea_3                   matlab.ui.control.TextArea
        UITable2                     matlab.ui.control.Table
        loadButton                   matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % Description
        
        alpha1 % Description
        alpha2
        alpha3
        alpha4
        alpha5
        alpha6
        a1
        a2
        a3
        a4
        a5
        a6
        d1
        d2
        d3
        d4
        d5
        d6
        theta1
        theta2
        theta3
        theta4
        theta5
        theta6
        alpha
        a
        d
        theta
        link_positions
        num_links
        i
        T_matrix
        max_limit 
        H
        Jacobian
        outputText
        modelPath
        data
        axes2
        limits 
        xLabel 
        yLabel 
        zLabel 
        viewAngle
        Children
        link_Position
        jointAngles
        resultText
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, T)
          
        end

        % Button pushed function: calculateTmatrixButton
        function calculateTmatrixButtonPushed(app, event)
            % definition of alpha values
            alpha1_value = app.edit_alpha_1.Value;
            assignin('base', 'alpha1', alpha1_value);
            alpha2_value = app.edit_alpha_2.Value;
            assignin('base', 'alpha2', alpha2_value);
            alpha3_value = app.edit_alpha_3.Value;
            assignin('base', 'alpha3', alpha3_value);
            alpha4_value = app.edit_alpha_4.Value;
            assignin('base', 'alpha4', alpha4_value);
            alpha5_value = app.edit_alpha_5.Value;
            assignin('base', 'alpha5', alpha5_value);
            alpha6_value = app.edit_alpha_6.Value;
            assignin('base', 'alpha6', alpha6_value);

            % definition of a values
            a1_value = app.edit_a_1.Value;
            assignin('base', 'a1', a1_value);
            a2_value = app.edit_a_2.Value;
            assignin('base', 'a2', a2_value);
            a3_value = app.edit_a_3.Value;
            assignin('base', 'a3', a3_value);
            a4_value = app.edit_a_4.Value;
            assignin('base', 'a4', a4_value);
            a5_value = app.edit_a_5.Value;
            assignin('base', 'a5', a5_value);
            a6_value = app.edit_a_6.Value;
            assignin('base', 'a6', a6_value);

            %definition of d values

            d1_value = app.edit_d_1.Value;
            assignin('base', 'd1', d1_value);
            d2_value = app.edit_d_2.Value;
            assignin('base', 'd2', d2_value);
            d3_value = app.edit_d_3.Value;
            assignin('base', 'd3', d3_value);
            d4_value = app.edit_d_4.Value;
            assignin('base', 'd4', d4_value);
            d5_value = app.edit_d_5.Value;
            assignin('base', 'd5', d5_value);
            d6_value = app.edit_d_6.Value;
            assignin('base', 'd6', d6_value);

            %definition of theta values
            theta1_value = app.edit_theta_1.Value;
            assignin('base', 'theta1', theta1_value);
            theta2_value = app.edit_theta_2.Value;
            assignin('base', 'theta2', theta2_value);
            theta3_value = app.edit_theta_3.Value;
            assignin('base', 'theta3', theta3_value);
            theta4_value = app.edit_theta_4.Value;
            assignin('base', 'theta4', theta4_value);
            theta5_value = app.edit_theta_5.Value;
            assignin('base', 'theta5', theta5_value);
            theta6_value = app.edit_theta_6.Value;
            assignin('base', 'theta6', theta6_value);

           DH = [deg2rad(alpha1_value) a1_value d1_value deg2rad(theta1_value);
               deg2rad(alpha2_value) a2_value d2_value deg2rad(theta2_value);
               deg2rad(alpha3_value) a3_value d3_value deg2rad(theta3_value);
               deg2rad(alpha4_value) a4_value d4_value deg2rad(theta4_value);
               deg2rad(alpha5_value) a5_value d5_value deg2rad(theta5_value);
               deg2rad(alpha6_value) a6_value d6_value deg2rad(theta6_value)];
           T = robotTransform(DH);
           disp(T);
           dh_table_str = evalc('disp(T)');
            app.TmatrixTextArea.Value = dh_table_str;
           assignin('base', 'T',T);
           %------------------------------------------------------------------
           %------------------------------------------------------------------
           alpha = [app.edit_alpha_1.Value, app.edit_alpha_2.Value, app.edit_alpha_3.Value, app.edit_alpha_4.Value, app.edit_alpha_5.Value, app.edit_alpha_6.Value];
        a = [app.edit_a_1.Value, app.edit_a_2.Value, app.edit_a_3.Value, app.edit_a_4.Value, app.edit_a_5.Value, app.edit_a_6.Value];
        d = [app.edit_d_1.Value, app.edit_d_2.Value, app.edit_d_3.Value, app.edit_d_4.Value, app.edit_d_5.Value, app.edit_d_6.Value];
        theta = [app.edit_theta_1.Value, app.edit_theta_2.Value, app.edit_theta_3.Value, app.edit_theta_4.Value, app.edit_theta_5.Value, app.edit_theta_6.Value];

        assignin('base', 'alpha', alpha);
        assignin('base', 'a', a);
        assignin('base', 'd', d);
        assignin('base', 'theta', theta);

        msgbox('Parameters saved to the workspace.', 'Info', 'info');
        end

        % Value changed function: edit_alpha_1
        function edit_alpha_1ValueChanged(app, event)
            value = app.edit_alpha_1.Value;
            app.alpha1=value;
        end

        % Value changed function: edit_alpha_2
        function edit_alpha_2ValueChanged(app, event)
            value = app.edit_alpha_2.Value;
            app.alpha2=value;
        end

        % Value changed function: edit_alpha_3
        function edit_alpha_3ValueChanged(app, event)
            value = app.edit_alpha_3.Value;
            app.alpha3=value;
        end

        % Value changed function: edit_alpha_4
        function edit_alpha_4ValueChanged(app, event)
            value = app.edit_alpha_4.Value;
             app.alpha4=value;
        end

        % Value changed function: edit_alpha_5
        function edit_alpha_5ValueChanged(app, event)
            value = app.edit_alpha_5.Value;
             app.alpha5=value;
        end

        % Value changed function: edit_alpha_6
        function edit_alpha_6ValueChanged(app, event)
            value = app.edit_alpha_6.Value;
             app.alpha6=value;
        end

        % Value changed function: edit_a_1
        function edit_a_1ValueChanged(app, event)
            value = app.edit_a_1.Value;
            app.a1=value;
        end

        % Value changed function: edit_a_2
        function edit_a_2ValueChanged(app, event)
            value = app.edit_a_2.Value;
            app.a2=value;
        end

        % Value changed function: edit_a_3
        function edit_a_3ValueChanged(app, event)
            value = app.edit_a_3.Value;
            app.a3=value;
        end

        % Value changed function: edit_a_4
        function edit_a_4ValueChanged(app, event)
            value = app.edit_a_4.Value;
            app.a4=value;
        end

        % Value changed function: edit_a_5
        function edit_a_5ValueChanged(app, event)
            value = app.edit_a_5.Value;
            app.a5=value;
        end

        % Value changed function: edit_a_6
        function edit_a_6ValueChanged(app, event)
            value = app.edit_a_6.Value;
            app.a6=value;
        end

        % Value changed function: edit_d_1
        function edit_d_1ValueChanged(app, event)
            value = app.edit_d_1.Value;
            app.d1=value;
        end

        % Value changed function: edit_d_2
        function edit_d_2ValueChanged(app, event)
            value = app.edit_d_2.Value;
            app.d2=value;
        end

        % Value changed function: edit_d_3
        function edit_d_3ValueChanged(app, event)
            value = app.edit_d_3.Value;
            app.d3=value;
        end

        % Value changed function: edit_d_4
        function edit_d_4ValueChanged(app, event)
            value = app.edit_d_4.Value;
            app.d4=value;
        end

        % Value changed function: edit_d_5
        function edit_d_5ValueChanged(app, event)
            value = app.edit_d_5.Value;
            app.d5=value;
        end

        % Value changed function: edit_d_6
        function edit_d_6ValueChanged(app, event)
            value = app.edit_d_6.Value;
            app.d6=value;
        end

        % Value changed function: edit_theta_1
        function edit_theta_1ValueChanged(app, event)
            value = app.edit_theta_1.Value;
            app.theta1=value;
            app.theta1Slider.Value = value;
        end

        % Value changed function: edit_theta_2
        function edit_theta_2ValueChanged(app, event)
            value = app.edit_theta_2.Value;
            app.theta2=value;
          app.theta2Slider.Value = value;
        end

        % Value changed function: edit_theta_3
        function edit_theta_3ValueChanged(app, event)
            value = app.edit_theta_3.Value;
            app.theta3=value;
            app.theta3Slider.Value = value;
        end

        % Value changed function: edit_theta_4
        function edit_theta_4ValueChanged(app, event)
            value = app.edit_theta_4.Value;
            app.theta4=value;
            app.theta4Slider.Value = value;
        end

        % Value changed function: edit_theta_5
        function edit_theta_5ValueChanged(app, event)
            value = app.edit_theta_5.Value;
            app.theta5=value;
            app.theta5Slider.Value = value;
        end

        % Value changed function: edit_theta_6
        function edit_theta_6ValueChanged(app, event)
            value = app.edit_theta_6.Value;
            app.theta6=value;
            app.theta6Slider.Value = value;
        end

        % Value changing function: theta1Slider
        function theta1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.edit_theta_1.Value = changingValue;
        end

        % Value changing function: theta2Slider
        function theta2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.edit_theta_2.Value = changingValue;
        end

        % Value changing function: theta3Slider
        function theta3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.edit_theta_3.Value = changingValue;
        end

        % Value changing function: theta4Slider
        function theta4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.edit_theta_4.Value = changingValue;
        end

        % Value changing function: theta5Slider
        function theta5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.edit_theta_5.Value = changingValue;
        end

        % Value changing function: theta6Slider
        function theta6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.edit_theta_6.Value = changingValue;
        end

        % Callback function
        function PlotButtonPushed(app, event)


        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
           
        % Call the robotTransform function
        
         if ~evalin('base', 'exist(''alpha'', ''var'') || exist(''a'', ''var'') || exist(''d'', ''var'') || exist(''theta'', ''var'')')
            msgbox('Please enter the parameters first.', 'Error', 'error');
            return;
        end

        % Retrieve the parameters from the base workspace
        alpha = evalin('base', 'alpha');
        a = evalin('base', 'a');
        d = evalin('base', 'd');
        theta = evalin('base', 'theta');

        % Calculate the transformation matrix
        num_links = length(theta);

        % Clear previous plots from axes
        cla(app.UIAxes);

        % Create figure for visualization
        axes(app.UIAxes);
        hold(app.UIAxes, 'on');

        % Initialize transformation matrix
        T = eye(4);

        % Store link positions for plotting
        link_positions = zeros(3, num_links + 1);
        for i = 1:num_links
            % Compute DH parameters
            A = [cosd(theta(i)), -sind(theta(i))*cosd(alpha(i)), sind(theta(i))*sind(alpha(i)), a(i)*cosd(theta(i));
                 sind(theta(i)), cosd(theta(i))*cosd(alpha(i)), -cosd(theta(i))*sind(alpha(i)), a(i)*sind(theta(i));
                 0, sind(alpha(i)), cosd(alpha(i)), d(i);
                 0, 0, 0, 1];

            % Update transformation matrix
            T = T * A;

            % Store link positions
            % Printing link positions
            link_positions(:, i+1) = T(1:3, 4);
            disp(['Link Position at Step ', num2str(i), ':']);
            disp(link_positions(:, i));
            %app.LinkPositionTextArea.Value = sprintf('link_position: %s', mat2str(link_positions));
            set(app.LinkPositionTextArea, 'Value', mat2str(link_positions));
   
            % Plot the link
            if i > 1
                plot3(app.UIAxes, link_positions(1, i-1:i), link_positions(2, i-1:i), link_positions(3, i-1:i), 'k-', 'LineWidth', 2);
            end
        end

        % Set axis limits
        %axis(app.UIAxes, 'equal');
      %  axis(app.UIAxes, [-10 10 -10 10 0 15]);
        max_limit = max(abs(T(1:3, 4))) + 1;  % Adjust as needed
        axis(app.UIAxes, [-max_limit max_limit -max_limit max_limit -max_limit max_limit]);

        % Set labels
        xlabel(app.UIAxes, 'X');
        ylabel(app.UIAxes, 'Y');
        zlabel(app.UIAxes, 'Z');

        % Set view angle
        view(app.UIAxes, 3);
        hold(app.UIAxes, 'off');
        end

        % Button pushed function: calculateJacobianButton
        function calculateJacobianButtonPushed(app, event)
            %calculate Jacobian matrix
            
            alpha = evalin('base', 'alpha');
            a = evalin('base', 'a');
            d = evalin('base', 'd');
            theta = evalin('base', 'theta');

            % Calculate Jacobian matrix
            Jacobian = calculateJacobian(alpha, a, d, theta);

            % Display Jacobian matrix in the TextArea
            set(app.JacobianmatrixTextArea, 'Value', mat2str(Jacobian));
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
         

   % showing DH parameters
       %alpha = evalin('base', 'alpha');
      % a = evalin('base', 'a');
       %d = evalin('base', 'd');
      alpha=[0;-90;0;-90;-90;90];
      a=[1.5;0;6.1;2;0;0];
      d=[4.5;0;0;6.3;0;0.8];
       theta = evalin('base', 'theta');
       data = cell(length(alpha), 4);

        % Fill in the cell array with values
        for i = 1:length(alpha)
            data{i, 1} = alpha(i);
            data{i, 2} = a(i);
            data{i, 3} = d(i);
            data{i, 4} = theta(i);
            app.UITable2.Data = data;
        end
       
        % robot visualiztaion
        %limits = axis(app.UIAxes);

        % Retrieve XLabel string directly using the 'get' function
        %xLabel = get(app.UIAxes.XLabel, 'String');
        
        % Retrieve YLabel and ZLabel strings directly
       % yLabel = get(app.UIAxes.YLabel, 'String');
       % zLabel = get(app.UIAxes.ZLabel, 'String');

       % viewAngle = get(app.UIAxes, 'View');

        % Replicate the plot in Axes2
        %copyobj(get(app.UIAxes, 'Children'), app.UIAxes2);
        %...................................................
         % Calculate the transformation matrix
        num_links = length(theta);

        % Clear previous plots from axes
        cla(app.UIAxes2);

        % Create figure for visualization
        axes(app.UIAxes2);
        hold(app.UIAxes2, 'on');

        % Initialize transformation matrix
        T = eye(4);

        % Store link positions for plotting
        link_positions = zeros(3, num_links + 1);
        for i = 1:num_links
            % Compute DH parameters
            A = [cosd(theta(i)), -sind(theta(i))*cosd(alpha(i)), sind(theta(i))*sind(alpha(i)), a(i)*cosd(theta(i));
                 sind(theta(i)), cosd(theta(i))*cosd(alpha(i)), -cosd(theta(i))*sind(alpha(i)), a(i)*sind(theta(i));
                 0, sind(alpha(i)), cosd(alpha(i)), d(i);
                 0, 0, 0, 1];

            % Update transformation matrix
            T = T * A;

            % Store link positions
            % Printing link positions
            link_positions(:, i+1) = T(1:3, 4);
            disp(['Link Position at Step ', num2str(i), ':']);
            disp(link_positions(:, i));
            %app.LinkPositionTextArea.Value = sprintf('link_position: %s', mat2str(link_positions));
            set(app.LinkPositionTextArea_2, 'Value', mat2str(link_positions));
   
            % Plot the link
            if i > 1
                plot3(app.UIAxes2, link_positions(1, i-1:i), link_positions(2, i-1:i), link_positions(3, i-1:i), 'k-', 'LineWidth', 2);
            end
        end

        % Set axis limits
        %axis(app.UIAxes, 'equal');
      %  axis(app.UIAxes, [-10 10 -10 10 0 15]);
        max_limit = max(abs(T(1:3, 4))) + 1;  % Adjust as needed
        axis(app.UIAxes2, [-max_limit max_limit -max_limit max_limit -max_limit max_limit]);

        % Set labels
        xlabel(app.UIAxes2, 'X');
        ylabel(app.UIAxes2, 'Y');
        zlabel(app.UIAxes2, 'Z');

        % Set view angle
        view(app.UIAxes2, 3);
        hold(app.UIAxes2, 'off');
        %...................................................

        % Set properties for Axes2
       % axis(app.UIAxes2, limits);
       % xlabel(app.UIAxes2, xLabel);
       % ylabel(app.UIAxes2, yLabel);
       % zlabel(app.UIAxes2, zLabel);
        %view(app.UIAxes2, viewAngle);
       
        % Link Position
       %set(app.LinkPositionTextArea_2, 'Value', app.LinkPositionTextArea.Value);
       modelPath = 'C:\Users\mohamed salah\Desktop\4th year 1st semster\robotik\robotic project\matlab\KR8_R1420_HW.slx';  % Replace with your actual file path
        % Check if the file exists
        % Open the Simscape model
   open_system(modelPath); 

        end

        % Button pushed function: InverseKinematicsFABRIKmethodButton
        function InverseKinematicsFABRIKmethodButtonPushed(app, event)
            
            alpha = evalin('base', 'alpha');
       a = evalin('base', 'a');
       d = evalin('base', 'd');
       theta = evalin('base', 'theta');
                    
            jointAngles = fabrik_inverse_kinematics(alpha, a, d, theta, [0, 0, 0], [2, 2, 2]);

        % Display joint angles with rotation matrices in the text area
        resultText = '';
        for i = 1:length(jointAngles)
            resultText = [resultText, sprintf('Joint %d Angle: %.2f\n', i, jointAngles(i))];
            
            % Calculate the rotation matrix for each link
            T_i = dh_matrix(alpha, a, d, theta, i, jointAngles);
            resultText = [resultText, sprintf('Rotation Matrix %dR%d:\n', i-1, i)];
            resultText = [resultText, sprintf('%.2f %.2f %.2f\n', T_i(1:3, 1))];
            resultText = [resultText, sprintf('%.2f %.2f %.2f\n', T_i(1:3, 2))];
            resultText = [resultText, sprintf('%.2f %.2f %.2f\n\n', T_i(1:3, 3))];
        end

        % Display the result text in the TextArea
        app.TextArea_4.Value = resultText;
        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.NnoneButton.Enable='off';
            app.PprizmaticButton.Enable='off';
            app.RrevoluteButton.Enable='off';
                   switch value
                       case 'none'
                           app.NnoneButton.Enable = 'on';
                       case 'd'
                           app.PprizmaticButton.Enable = 'on';
                       case 'theta'
                           app.RrevoluteButton.Enable = 'on';
                   end

            
        end

        % Callback function
        function DropDown_7ValueChanged(app, event)
            
        end

        % Callback function
        function DropDown_7ValueChanged2(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged2(app, event)
            value = app.DropDown.Value;
            app.RrevoluteButton.Enable = 'off';
            app.PprizmaticButton.Enable = 'off';
            app.NnoneButton.Enable = 'off';

            % Enable the corresponding button based on the selected value
            switch value
                case 'none'
                    app.NnoneButton.Enable = 'on';
                case 'd'
                    app.PprizmaticButton.Enable = 'on';
                case 'theta'
                    app.RrevoluteButton.Enable = 'on';
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            app.RrevoluteButton_2.Enable = 'off';
            app.PprizmaticButton_2.Enable = 'off';
            app.NnoneButton_2.Enable= 'off';

            % Enable the corresponding button based on the selected value
            switch value
                case 'none'
                    app.NnoneButton_2.Enable = 'on';
                case 'd'
                    app.PprizmaticButton_2.Enable = 'on';
                case 'theta'
                    app.RrevoluteButton_2.Enable = 'on';
            end
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;
            app.RrevoluteButton_3.Enable = 'off';
            app.PprizmaticButton_3.Enable = 'off';
            app.NnoneButton_3.Enable= 'off';

            % Enable the corresponding button based on the selected value
            switch value
                case 'none'
                    app.NnoneButton_3.Enable = 'on';
                case 'd'
                    app.PprizmaticButton_3.Enable = 'on';
                case 'theta'
                    app.RrevoluteButton_3.Enable = 'on';
            end
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            app.RrevoluteButton_4.Enable = 'off';
            app.PprizmaticButton_4.Enable = 'off';
            app.NnoneButton_4.Enable= 'off';

            % Enable the corresponding button based on the selected value
            switch value
                case 'none'
                    app.NnoneButton_4.Enable = 'on';
                case 'd'
                    app.PprizmaticButton_4.Enable = 'on';
                case 'theta'
                    app.RrevoluteButton_4.Enable = 'on';
            end
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            value = app.DropDown_6.Value;
            app.RrevoluteButton_5.Enable = 'off';
            app.PprizmaticButton_5.Enable = 'off';
            app.NnoneButton_5.Enable= 'off';

            % Enable the corresponding button based on the selected value
            switch value
                case 'none'
                    app.NnoneButton_5.Enable = 'on';
                case 'd'
                    app.PprizmaticButton_5.Enable = 'on';
                case 'theta'
                    app.RrevoluteButton_5.Enable = 'on';
            end
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged3(app, event)
            value = app.DropDown_7.Value;
            app.RrevoluteButton_6.Enable = 'off';
            app.PprizmaticButton_6.Enable = 'off';
            app.NnoneButton_6.Enable= 'off';

            % Enable the corresponding button based on the selected value
            switch value
                case 'none'
                    app.NnoneButton_6.Enable = 'on';
                case 'd'
                    app.PprizmaticButton_6.Enable = 'on';
                case 'theta'
                    app.RrevoluteButton_6.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.generalTab = uitab(app.TabGroup);
            app.generalTab.Title = ' general';
            app.UIAxes = uiaxes(app.generalTab);
            title(app.UIAxes, 'robotVisualization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.generalTab);
            app.UITable.ColumnName = {'alpha'; 'a'; 'd'; 'theta'};
            app.UITable.RowName = {};
            app.edit_theta_1 = uieditfield(app.generalTab, 'numeric');
            app.edit_theta_1.ValueChangedFcn = createCallbackFcn(app, @edit_theta_1ValueChanged, true);
            app.edit_theta_1.Tag = 'theta_1';
            app.edit_alpha_2 = uieditfield(app.generalTab, 'numeric');
            app.edit_alpha_2.ValueChangedFcn = createCallbackFcn(app, @edit_alpha_2ValueChanged, true);
            app.edit_alpha_2.Tag = 'alpha_2';
            app.edit_alpha_3 = uieditfield(app.generalTab, 'numeric');
            app.edit_alpha_3.ValueChangedFcn = createCallbackFcn(app, @edit_alpha_3ValueChanged, true);
            app.edit_alpha_3.Tag = 'alpha_3';
            app.edit_alpha_4 = uieditfield(app.generalTab, 'numeric');
            app.edit_alpha_4.ValueChangedFcn = createCallbackFcn(app, @edit_alpha_4ValueChanged, true);
            app.edit_alpha_4.Tag = 'alpha_4';
            app.edit_alpha_5 = uieditfield(app.generalTab, 'numeric');
            app.edit_alpha_5.ValueChangedFcn = createCallbackFcn(app, @edit_alpha_5ValueChanged, true);
            app.edit_alpha_5.Tag = 'alpha_5';
            app.edit_alpha_6 = uieditfield(app.generalTab, 'numeric');
            app.edit_alpha_6.ValueChangedFcn = createCallbackFcn(app, @edit_alpha_6ValueChanged, true);
            app.edit_alpha_6.Tag = 'alpha_6';
            app.edit_a_2 = uieditfield(app.generalTab, 'numeric');
            app.edit_a_2.ValueChangedFcn = createCallbackFcn(app, @edit_a_2ValueChanged, true);
            app.edit_a_2.Tag = 'a_2';
            app.edit_a_3 = uieditfield(app.generalTab, 'numeric');
            app.edit_a_3.ValueChangedFcn = createCallbackFcn(app, @edit_a_3ValueChanged, true);
            app.edit_a_3.Tag = 'a_3';
            app.edit_a_4 = uieditfield(app.generalTab, 'numeric');
            app.edit_a_4.ValueChangedFcn = createCallbackFcn(app, @edit_a_4ValueChanged, true);
            app.edit_a_4.Tag = 'a_4';
            app.edit_a_5 = uieditfield(app.generalTab, 'numeric');
            app.edit_a_5.ValueChangedFcn = createCallbackFcn(app, @edit_a_5ValueChanged, true);
            app.edit_a_5.Tag = 'a_5';
            app.edit_a_6 = uieditfield(app.generalTab, 'numeric');
            app.edit_a_6.ValueChangedFcn = createCallbackFcn(app, @edit_a_6ValueChanged, true);
            app.edit_a_6.Tag = 'a_6';
            app.edit_d_2 = uieditfield(app.generalTab, 'numeric');
            app.edit_d_2.ValueChangedFcn = createCallbackFcn(app, @edit_d_2ValueChanged, true);
            app.edit_d_2.Tag = 'd_2';
            app.edit_d_3 = uieditfield(app.generalTab, 'numeric');
            app.edit_d_3.ValueChangedFcn = createCallbackFcn(app, @edit_d_3ValueChanged, true);
            app.edit_d_3.Tag = 'd_3';
            app.edit_d_4 = uieditfield(app.generalTab, 'numeric');
            app.edit_d_4.ValueChangedFcn = createCallbackFcn(app, @edit_d_4ValueChanged, true);
            app.edit_d_4.Tag = 'd_4';
            app.edit_d_5 = uieditfield(app.generalTab, 'numeric');
            app.edit_d_5.ValueChangedFcn = createCallbackFcn(app, @edit_d_5ValueChanged, true);
            app.edit_d_5.Tag = 'd_5';
            app.edit_d_6 = uieditfield(app.generalTab, 'numeric');
            app.edit_d_6.ValueChangedFcn = createCallbackFcn(app, @edit_d_6ValueChanged, true);
            app.edit_d_6.Tag = 'd_6';
            app.edit_theta_2 = uieditfield(app.generalTab, 'numeric');
            app.edit_theta_2.ValueChangedFcn = createCallbackFcn(app, @edit_theta_2ValueChanged, true);
            app.edit_theta_2.Tag = 'theta_2';
            app.edit_theta_3 = uieditfield(app.generalTab, 'numeric');
            app.edit_theta_3.ValueChangedFcn = createCallbackFcn(app, @edit_theta_3ValueChanged, true);
            app.edit_theta_3.Tag = 'theta_3';
            app.edit_theta_4 = uieditfield(app.generalTab, 'numeric');
            app.edit_theta_4.ValueChangedFcn = createCallbackFcn(app, @edit_theta_4ValueChanged, true);
            app.edit_theta_4.Tag = 'theta_4';
            app.edit_theta_5 = uieditfield(app.generalTab, 'numeric');
            app.edit_theta_5.ValueChangedFcn = createCallbackFcn(app, @edit_theta_5ValueChanged, true);
            app.edit_theta_5.Tag = 'theta_5';
            app.edit_theta_6 = uieditfield(app.generalTab, 'numeric');
            app.edit_theta_6.ValueChangedFcn = createCallbackFcn(app, @edit_theta_6ValueChanged, true);
            app.edit_theta_6.Tag = 'theta_6';
            app.TextArea = uitextarea(app.generalTab);
            app.TextArea.Value = {'DH table parameters'};
            app.edit_d_1 = uieditfield(app.generalTab, 'numeric');
            app.edit_d_1.ValueChangedFcn = createCallbackFcn(app, @edit_d_1ValueChanged, true);
            app.edit_d_1.Tag = 'd_1';
            app.edit_a_1 = uieditfield(app.generalTab, 'numeric');
            app.edit_a_1.ValueChangedFcn = createCallbackFcn(app, @edit_a_1ValueChanged, true);
            app.edit_a_1.Tag = 'a_1';
            app.Label_2 = uilabel(app.generalTab);
            app.Label_2.Text = '1';
            app.Label_3 = uilabel(app.generalTab);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.generalTab);
            app.Label_4.Text = '3';
            app.Label_5 = uilabel(app.generalTab);
            app.Label_5.Text = '4';
            app.Label_6 = uilabel(app.generalTab);
            app.Label_6.Text = '5';
            app.Label_7 = uilabel(app.generalTab);
            app.Label_7.Text = '6';
            app.TextArea_2 = uitextarea(app.generalTab);
            app.TextArea_2.Value = {'Link'};
            app.calculateTmatrixButton = uibutton(app.generalTab, 'push');
            app.calculateTmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @calculateTmatrixButtonPushed, true);
            app.calculateTmatrixButton.Text = 'calculate T matrix';
            app.edit_alpha_1 = uieditfield(app.generalTab, 'numeric');
            app.edit_alpha_1.ValueChangedFcn = createCallbackFcn(app, @edit_alpha_1ValueChanged, true);
            app.edit_alpha_1.Tag = 'alpha_1';
            app.TmatrixTextAreaLabel = uilabel(app.generalTab);
            app.TmatrixTextAreaLabel.Text = 'T matrix';
            app.TmatrixTextArea = uitextarea(app.generalTab);
            app.theta1SliderLabel = uilabel(app.generalTab);
            app.theta1SliderLabel.Text = 'theta1';
            app.theta1Slider = uislider(app.generalTab);
            app.theta1Slider.Limits = [-360 360];
            app.theta1Slider.MajorTicks = [-360 0 360];
            app.theta1Slider.ValueChangingFcn = createCallbackFcn(app, @theta1SliderValueChanging, true);
            app.theta2SliderLabel = uilabel(app.generalTab);
            app.theta2SliderLabel.Text = 'theta2';
            app.theta2Slider = uislider(app.generalTab);
            app.theta2Slider.Limits = [-360 360];
            app.theta2Slider.MajorTicks = [-360 0 360];
            app.theta2Slider.ValueChangingFcn = createCallbackFcn(app, @theta2SliderValueChanging, true);
            app.theta3SliderLabel = uilabel(app.generalTab);
            app.theta3SliderLabel.Text = 'theta3';
            app.theta3Slider = uislider(app.generalTab);
            app.theta3Slider.Limits = [-360 360];
            app.theta3Slider.MajorTicks = [-360 0 360];
            app.theta3Slider.ValueChangingFcn = createCallbackFcn(app, @theta3SliderValueChanging, true);
            app.theta4SliderLabel = uilabel(app.generalTab);
            app.theta4SliderLabel.Text = 'theta4';
            app.theta4Slider = uislider(app.generalTab);
            app.theta4Slider.Limits = [-360 360];
            app.theta4Slider.MajorTicks = [-360 0 360];
            app.theta4Slider.ValueChangingFcn = createCallbackFcn(app, @theta4SliderValueChanging, true);
            app.theta5SliderLabel = uilabel(app.generalTab);
            app.theta5SliderLabel.Text = 'theta5';
            app.theta5Slider = uislider(app.generalTab);
            app.theta5Slider.Limits = [-360 360];
            app.theta5Slider.MajorTicks = [-360 0 360];
            app.theta5Slider.ValueChangingFcn = createCallbackFcn(app, @theta5SliderValueChanging, true);
            app.theta6SliderLabel = uilabel(app.generalTab);
            app.theta6SliderLabel.Text = 'theta6';
            app.theta6Slider = uislider(app.generalTab);
            app.theta6Slider.Limits = [-360 360];
            app.theta6Slider.MajorTicks = [-360 0 360];
            app.theta6Slider.ValueChangingFcn = createCallbackFcn(app, @theta6SliderValueChanging, true);
            app.plotButton = uibutton(app.generalTab, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.calculateJacobianButton = uibutton(app.generalTab, 'push');
            app.calculateJacobianButton.ButtonPushedFcn = createCallbackFcn(app, @calculateJacobianButtonPushed, true);
            app.calculateJacobianButton.Text = 'calculate Jacobian';
            app.JacobianmatrixTextAreaLabel = uilabel(app.generalTab);
            app.JacobianmatrixTextAreaLabel.Text = 'Jacobian matrix';
            app.JacobianmatrixTextArea = uitextarea(app.generalTab);
            app.LinkPositionTextAreaLabel = uilabel(app.generalTab);
            app.LinkPositionTextAreaLabel.Text = 'Link Position';
            app.LinkPositionTextArea = uitextarea(app.generalTab);
            app.ButtonGroup = uibuttongroup(app.generalTab);
            app.RrevoluteButton = uiradiobutton(app.ButtonGroup);
            app.RrevoluteButton.Text = 'R''revolute''';
            app.PprizmaticButton = uiradiobutton(app.ButtonGroup);
            app.PprizmaticButton.Text = 'P''prizmatic''';
            app.NnoneButton = uiradiobutton(app.ButtonGroup);
            app.NnoneButton.Text = 'N''none''';
            app.NnoneButton.Value = true;
            app.ButtonGroup_2 = uibuttongroup(app.generalTab);
            app.RrevoluteButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.RrevoluteButton_2.Text = 'R''revolute''';
            app.PprizmaticButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.PprizmaticButton_2.Text = 'P''prizmatic''';
            app.NnoneButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.NnoneButton_2.Text = 'N''none''';
            app.NnoneButton_2.Value = true;
            app.ButtonGroup_3 = uibuttongroup(app.generalTab);
            app.RrevoluteButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.RrevoluteButton_3.Text = 'R''revolute''';
            app.PprizmaticButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.PprizmaticButton_3.Text = 'P''prizmatic''';
            app.NnoneButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.NnoneButton_3.Text = 'N''none''';
            app.NnoneButton_3.Value = true;
            app.ButtonGroup_4 = uibuttongroup(app.generalTab);
            app.RrevoluteButton_4 = uiradiobutton(app.ButtonGroup_4);
            app.RrevoluteButton_4.Text = 'R''revolute''';
            app.PprizmaticButton_4 = uiradiobutton(app.ButtonGroup_4);
            app.PprizmaticButton_4.Text = 'P''prizmatic''';
            app.NnoneButton_4 = uiradiobutton(app.ButtonGroup_4);
            app.NnoneButton_4.Text = 'N''none''';
            app.NnoneButton_4.Value = true;
            app.ButtonGroup_5 = uibuttongroup(app.generalTab);
            app.RrevoluteButton_5 = uiradiobutton(app.ButtonGroup_5);
            app.RrevoluteButton_5.Text = 'R''revolute''';
            app.PprizmaticButton_5 = uiradiobutton(app.ButtonGroup_5);
            app.PprizmaticButton_5.Text = 'P''prizmatic''';
            app.NnoneButton_5 = uiradiobutton(app.ButtonGroup_5);
            app.NnoneButton_5.Text = 'N''none''';
            app.NnoneButton_5.Value = true;
            app.ButtonGroup_6 = uibuttongroup(app.generalTab);
            app.RrevoluteButton_6 = uiradiobutton(app.ButtonGroup_6);
            app.RrevoluteButton_6.Text = 'R''revolute''';
            app.PprizmaticButton_6 = uiradiobutton(app.ButtonGroup_6);
            app.PprizmaticButton_6.Text = 'P''prizmatic''';
            app.NnoneButton_6 = uiradiobutton(app.ButtonGroup_6);
            app.NnoneButton_6.Text = 'N''none''';
            app.NnoneButton_6.Value = true;
            app.DropDown = uidropdown(app.generalTab);
            app.DropDown.Items = {'none', 'd', 'theta'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged2, true);
            app.DropDown.Value = 'none';
            app.DropDown_3 = uidropdown(app.generalTab);
            app.DropDown_3.Items = {'none', 'd', 'theta'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'none';
            app.DropDown_4 = uidropdown(app.generalTab);
            app.DropDown_4.Items = {'none', 'd', 'theta'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = 'none';
            app.DropDown_5 = uidropdown(app.generalTab);
            app.DropDown_5.Items = {'none', 'd', 'theta'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = 'none';
            app.DropDown_6 = uidropdown(app.generalTab);
            app.DropDown_6.Items = {'none', 'd', 'theta'};
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Value = 'none';
            app.DropDown_7 = uidropdown(app.generalTab);
            app.DropDown_7.Items = {'none', 'd', 'theta'};
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged3, true);
            app.DropDown_7.Value = 'none';
            app.KUKAKR8R1420HWTab = uitab(app.TabGroup);
            app.KUKAKR8R1420HWTab.Title = 'KUKA KR8 R 1420 HW';
            app.UIAxes2 = uiaxes(app.KUKAKR8R1420HWTab);
            title(app.UIAxes2, 'KUKA KR8 R 1420 HW Visualization')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.loadButton = uibutton(app.KUKAKR8R1420HWTab, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.UITable2 = uitable(app.KUKAKR8R1420HWTab);
            app.UITable2.ColumnName = {'alpha'; 'a'; 'd'; 'theta'};
            app.UITable2.RowName = {'Link1'; 'Link2'; 'Link3'; 'Link4'; 'Link5'; 'Link6'};
            app.TextArea_3 = uitextarea(app.KUKAKR8R1420HWTab);
            app.TextArea_3.Value = {'DH table parameters'};
            app.LinkPositionTextArea_2Label = uilabel(app.KUKAKR8R1420HWTab);
            app.LinkPositionTextArea_2Label.Text = 'Link Position';
            app.LinkPositionTextArea_2 = uitextarea(app.KUKAKR8R1420HWTab);
            app.InverseKinematicsFABRIKmethodButton = uibutton(app.KUKAKR8R1420HWTab, 'push');
            app.InverseKinematicsFABRIKmethodButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsFABRIKmethodButtonPushed, true);
            app.InverseKinematicsFABRIKmethodButton.Text = 'Inverse Kinematics FABRIK method';
            app.InverseKinematicsCCDmethodButton = uibutton(app.KUKAKR8R1420HWTab, 'push');
            app.InverseKinematicsCCDmethodButton.Text = 'Inverse Kinematics CCD method';
            app.TextArea_4 = uitextarea(app.KUKAKR8R1420HWTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end